classdef single_resonator_designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        DesignTab                      matlab.ui.container.Tab
        ResultsPanel                   matlab.ui.container.Panel
        DeltatopEditField              matlab.ui.control.NumericEditField
        DeltatopEditFieldLabel         matlab.ui.control.Label
        DeltaPiezoEditField            matlab.ui.control.NumericEditField
        DeltaPiezoEditFieldLabel       matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        bottomelectrodeLabel           matlab.ui.control.Label
        piezoLabel                     matlab.ui.control.Label
        topelectrodeLabel              matlab.ui.control.Label
        SolutionsDropDown              matlab.ui.control.DropDown
        SolutionsDropDownLabel         matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        ProjectRequirementsPanel       matlab.ui.container.Panel
        InputparametersLabel           matlab.ui.control.Label
        DesignButton                   matlab.ui.control.Button
        PiezothicknessmEditField       matlab.ui.control.EditField
        PiezothicknessmEditFieldLabel  matlab.ui.control.Label
        Metallayer1ListBox             matlab.ui.control.ListBox
        Metallayer1ListBoxLabel        matlab.ui.control.Label
        ResonantFrequencyHzEditField   matlab.ui.control.EditField
        ResonantFrequencyHzEditFieldLabel  matlab.ui.control.Label
        NewmaterialTab                 matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        PiezothicknessEditField        matlab.ui.control.NumericEditField
        PiezothicknessEditFieldLabel   matlab.ui.control.Label
        NumValuesEditField             matlab.ui.control.NumericEditField
        NumValuesEditFieldLabel        matlab.ui.control.Label
        MaxmetalEditField              matlab.ui.control.NumericEditField
        MaxmetalEditFieldLabel         matlab.ui.control.Label
        MinmetalEditField              matlab.ui.control.NumericEditField
        MinmetalEditFieldLabel         matlab.ui.control.Label
        PreviewButton_2                matlab.ui.control.Button
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        PathEditField                  matlab.ui.control.EditField
        PathEditFieldLabel             matlab.ui.control.Label
        UploadButton_3                 matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        h_piezo=0;
        metal="aluminium";% Desription
        fl=0; % Description
        fu=0;
        bw=0;
        filtersFamily % Description
    end
    
    methods (Access = private)
        function displaySolutions(app)
            h_metal= zeros(size(app.filtersFamily,1),1);
            h_p= zeros(size(app.filtersFamily,1),1);
            
            for i = 1: size(app.filtersFamily,2)
                h_p(i)=app.filtersFamily(i).h_piezo;
                h_metal(i)=app.filtersFamily(i).h_metal;
            end
            
       %   app.UIAxes_2.cla;
          plot(app.UIAxes_2,h_p.*10^6,h_metal.*10^9,'LineWidth',1.5); grid(app.UIAxes_2,'on');
        %  AI
 
%           figure
%           plot(h_p.*10^6,h_metal(:,1).*10^9,'LineWidth',1.5);
%           figure
%           plot(h_p.*10^6,h_metal(:,2).*10^9,'LineWidth',1.5);
          %plot(h_p.*10^6,h_metal(:,2).*10^9,'LineWidth',1.5); hold on; grid on;
          %plot(h_p.*10^6,h_metal(:,1).*10^9,'LineWidth',1.5);

          %xlabel("h/d");
          %ylabel("h_{metal,1} [nm]");
          %          ax=gca;
         %ax.FontSize=25;
        
        end
        
        function updateSolutions(app)
            app.SolutionsDropDown.Items(:)=[];
           
            for i = 1: size(app.filtersFamily,2)
            str=num2str(i)+" h_piezo :"+num2str(app.filtersFamily(i).h_piezo);
            app.SolutionsDropDown.Items=[app.SolutionsDropDown.Items str];
            end
        end
        
        function updateMaterials(app)
            fid=fopen("./materials.txt",'r');
            txt = textscan(fid,'%s','delimiter','\n');
            app.Metallayer1ListBox.Items=txt{1,1};
           
        end
        
        function results = selected_solution(app)
                   value = app.SolutionsDropDown.Value;
            number_str=strsplit(string(value));
            i= str2double(number_str(1));
            lz=1e-6;
            h_bottom=app.filtersFamily(i).h_piezo*0.16;
            piezo=app.filtersFamily(i).h_piezo;
           top=app.filtersFamily(i).h_metal;
            app.topelectrodeLabel.Text="top-electrode: "+string(top.*1e9)+" nm";
            app.piezoLabel.Text="piezo: "+string(piezo.*1e6)+" \mum";
            app.bottomelectrodeLabel.Text="bottom-electrode: "+string(h_bottom*1e9)+" nm";
            %PrintSolution_resonator(app.filtersFamily(i));
            % plot bottom
            rectangle(app.UIAxes_3,'Position', [0 -h_bottom lz h_bottom],'FaceColor',[1/size(top,2), 1/size(top,2), 0.6 ]);
            % plot piezo
             rectangle(app.UIAxes_3,'Position', [0 0 lz piezo],'FaceColor','blue');
            % plot top
            rectangle(app.UIAxes_3,'Position', [0 piezo lz top],'FaceColor','k');
            %[fl,fu]=monteCarloSimulation(app.filtersFamily(i),true)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.updateMaterials();
        end

        % Value changed function: Metallayer1ListBox
        function Metallayer1ListBoxValueChanged(app, event)
           value = app.Metallayer1ListBox.Value;
           app.metal=value;
        end

        % Value changed function: ResonantFrequencyHzEditField
        function ResonantFrequencyHzEditFieldValueChanged(app, event)
            value = app.ResonantFrequencyHzEditField.Value;
            app.fl=value;
        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            app.filtersFamily=design_resonator(app.h_piezo,app.metal,app.fl);
            app.displaySolutions();
            app.updateSolutions();
            app.selected_solution();
        end

        % Value changed function: SolutionsDropDown
        function SolutionsDropDownValueChanged(app, event)
     app.selected_solution();
        end

        % Callback function
        function UploadButtonPushed(app, event)
            [file, path]=uigetfile("*.txt");
            app.SeriesEditField.Value=[path file];
        end

        % Callback function
        function UploadButton_2Pushed(app, event)
            [file, path]=uigetfile("*.txt");
            app.ShuntEditField.Value=[path file];
        end

        % Value changed function: PiezothicknessmEditField
        function PiezothicknessmEditFieldValueChanged(app, event)
            value = app.PiezothicknessmEditField.Value;
            app.h_piezo=value;
        end

        % Button pushed function: UploadButton_3
        function UploadButton_3Pushed(app, event)
            [file, path]=uigetfile("*.txt");
            app.PathEditField.Value=[path file];           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [x,y]=calibrationCurveMaker(app.PathEditField.Value, app.NameEditField.Value,app.MinmetalEditField.Value,app.MaxmetalEditField.Value,app.NumValuesEditField.Value,app.PiezothicknessEditField.Value);
            save_path="./PH-VELOCITIES/"+string(app.NameEditField.Value);
            name=string(app.NameEditField.Value);
            if(exist(save_path+".txt",'file')>0)
                name=string(app.NameEditField.Value)+"_"+string(date);
            end
            save_path="./PH-VELOCITIES/"+name+".txt";
            fileID_1 = fopen(save_path,'w');
            fileID_2=fopen("./materials.txt",'a');
            for i = 1: size(y,2)
                fprintf(fileID_1,"%d %4.5d \r\n",y(i), x(i));
            end
            fclose(fileID_1);
            str="\n"+name;
            fprintf(fileID_2,str);
            fclose(fileID_2);
            app.updateMaterials();
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
           [file, path]=uigetfile("*.txt");
            resonator=loadResonator([path file]);
            app.ResonantFrequencyHzEditField.Value=resonator.fl;
        
            app.Metallayer1ListBox.Value=resonator.metal;

            app.PiezothicknessmEditField.Value=resonator.hp;

            app.metal=resonator.metal;
        
            app.h_piezo=resonator.hp;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            fid=fopen("./RESONATORS/resonator_"+string(date)+".txt","w+");
            metals=app.metal;
            fprintf(fid,"%s,%s,metal=["+metals+"]",app.ResonantFrequencyHzEditField.Value,app.PiezothicknessmEditField.Value);        
            fclose(fid);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            value = app.SolutionsDropDown.Value;
            number_str=strsplit(string(value));
            i= str2double(number_str(1));
            monteCarloSimulation(app.filtersFamily(i),true,app.DeltaPiezoEditField.Value,app.DeltatopEditField.Value); 
        end

        % Value changed function: DeltaPiezoEditField
        function DeltaPiezoEditFieldValueChanged(app, event)
            value = app.DeltaPiezoEditField.Value;
            if (value)<0
               errordlg("ERROR! Tollerance must be a positive number!");
            else
                if value>=0.1
                    warndlg("Tollerance is too high values may not have sense!");
                end
            end
        end

        % Value changed function: DeltatopEditField
        function DeltatopEditFieldValueChanged(app, event)
            value = app.DeltatopEditField.Value;
                        value = app.DeltatopEditField.Value;
            if (value)<0
               errordlg("ERROR! Tollerance must be a positive number!");
            else
                if value>=0.1
                    warndlg("Tollerance is too high values may not have sense!");
                end
            end
        end

        % Button pushed function: PreviewButton_2
        function PreviewButton_2Pushed(app, event)
                        [x,y]=calibrationCurveMaker(app.PathEditField.Value, app.NameEditField.Value,app.MinmetalEditField.Value,app.MaxmetalEditField.Value,app.NumValuesEditField.Value,app.PiezothicknessEditField.Value);
            plot(app.UIAxes3,x,y,'-o','LineWidth',2);
            hold(app.UIAxes3,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenMenu = uimenu(app.Menu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.ProjectRequirementsPanel = uipanel(app.DesignTab);
            app.ProjectRequirementsPanel.Title = 'Project Requirements';
            app.ResonantFrequencyHzEditFieldLabel = uilabel(app.ProjectRequirementsPanel);
            app.ResonantFrequencyHzEditFieldLabel.Text = 'Resonant Frequency [Hz]';
            app.ResonantFrequencyHzEditField = uieditfield(app.ProjectRequirementsPanel, 'text');
            app.ResonantFrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @ResonantFrequencyHzEditFieldValueChanged, true);
            app.Metallayer1ListBoxLabel = uilabel(app.ProjectRequirementsPanel);
            app.Metallayer1ListBoxLabel.Text = {'Metal layer #1'; ''};
            app.Metallayer1ListBox = uilistbox(app.ProjectRequirementsPanel);
            app.Metallayer1ListBox.Items = {'Aluminium', 'Silver', 'Copper', 'Gold', 'Titanium', 'Molybdenum'};
            app.Metallayer1ListBox.ValueChangedFcn = createCallbackFcn(app, @Metallayer1ListBoxValueChanged, true);
            app.Metallayer1ListBox.Value = 'Aluminium';
            app.PiezothicknessmEditFieldLabel = uilabel(app.ProjectRequirementsPanel);
            app.PiezothicknessmEditFieldLabel.Text = 'Piezo thickness [m]';
            app.PiezothicknessmEditField = uieditfield(app.ProjectRequirementsPanel, 'text');
            app.PiezothicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @PiezothicknessmEditFieldValueChanged, true);
            app.DesignButton = uibutton(app.ProjectRequirementsPanel, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Design';
            app.InputparametersLabel = uilabel(app.ProjectRequirementsPanel);
            app.InputparametersLabel.Text = 'Input parameters';
            app.ResultsPanel = uipanel(app.DesignTab);
            app.ResultsPanel.Title = 'Results';
            app.UIAxes_2 = uiaxes(app.ResultsPanel);
            xlabel(app.UIAxes_2, 'h_{piezo} [\mum] ')
            ylabel(app.UIAxes_2, 'h_{metal} [nm]')
            app.UIAxes_3 = uiaxes(app.ResultsPanel);
            xlabel(app.UIAxes_3, 'h_{piezo} [\mum] ')
            ylabel(app.UIAxes_3, 'h_{metal} [nm]')
            app.SolutionsDropDownLabel = uilabel(app.ResultsPanel);
            app.SolutionsDropDownLabel.Text = 'Solutions';
            app.SolutionsDropDown = uidropdown(app.ResultsPanel);
            app.SolutionsDropDown.Items = {};
            app.SolutionsDropDown.ValueChangedFcn = createCallbackFcn(app, @SolutionsDropDownValueChanged, true);
            app.SolutionsDropDown.Value = {};
            app.topelectrodeLabel = uilabel(app.ResultsPanel);
            app.topelectrodeLabel.Text = 'top-electrode: ';
            app.piezoLabel = uilabel(app.ResultsPanel);
            app.piezoLabel.Text = 'piezo:';
            app.bottomelectrodeLabel = uilabel(app.ResultsPanel);
            app.bottomelectrodeLabel.Text = 'bottom-electrode:';
            app.RunButton = uibutton(app.ResultsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DeltaPiezoEditFieldLabel = uilabel(app.ResultsPanel);
            app.DeltaPiezoEditFieldLabel.Text = 'Delta-Piezo';
            app.DeltaPiezoEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.DeltaPiezoEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaPiezoEditFieldValueChanged, true);
            app.DeltatopEditFieldLabel = uilabel(app.ResultsPanel);
            app.DeltatopEditFieldLabel.Text = 'Delta-top';
            app.DeltatopEditField = uieditfield(app.ResultsPanel, 'numeric');
            app.DeltatopEditField.ValueChangedFcn = createCallbackFcn(app, @DeltatopEditFieldValueChanged, true);
            app.NewmaterialTab = uitab(app.TabGroup);
            app.NewmaterialTab.Title = 'New material';
            app.UIAxes3 = uiaxes(app.NewmaterialTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'h/d')
            ylabel(app.UIAxes3, 'v_p [m/s]')
            app.UploadButton_3 = uibutton(app.NewmaterialTab, 'push');
            app.UploadButton_3.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_3Pushed, true);
            app.UploadButton_3.Icon = '512x512.png';
            app.UploadButton_3.Text = 'Upload';
            app.PathEditFieldLabel = uilabel(app.NewmaterialTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.NewmaterialTab, 'text');
            app.NameEditFieldLabel = uilabel(app.NewmaterialTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.NewmaterialTab, 'text');
            app.PreviewButton_2 = uibutton(app.NewmaterialTab, 'push');
            app.PreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviewButton_2Pushed, true);
            app.PreviewButton_2.Text = 'Preview';
            app.MinmetalEditFieldLabel = uilabel(app.NewmaterialTab);
            app.MinmetalEditFieldLabel.Text = 'Min metal';
            app.MinmetalEditField = uieditfield(app.NewmaterialTab, 'numeric');
            app.MaxmetalEditFieldLabel = uilabel(app.NewmaterialTab);
            app.MaxmetalEditFieldLabel.Text = 'Max metal';
            app.MaxmetalEditField = uieditfield(app.NewmaterialTab, 'numeric');
            app.NumValuesEditFieldLabel = uilabel(app.NewmaterialTab);
            app.NumValuesEditFieldLabel.Text = 'Num Values';
            app.NumValuesEditField = uieditfield(app.NewmaterialTab, 'numeric');
            app.PiezothicknessEditFieldLabel = uilabel(app.NewmaterialTab);
            app.PiezothicknessEditFieldLabel.Text = 'Piezo thickness';
            app.PiezothicknessEditField = uieditfield(app.NewmaterialTab, 'numeric');
            app.Button = uibutton(app.NewmaterialTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '0_vdUY_dRmKtBvgYaM.jpeg';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = single_resonator_designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end